classdef app_bif < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BifurcationanalysisUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        gl_params                    matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        LossValue                    matlab.ui.control.Label
        LossvalueRMSELabel           matlab.ui.control.Label
        DatasetDropDown              matlab.ui.control.DropDown
        DatasetDropDownLabel         matlab.ui.control.Label
        ax_bif                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        model % Description
        param_comps = {};
        data_fit = [];
        ind_data_set = 0;
        ind_bif_param = 0;
        line_profile_stable;
        line_profile_unstable;
        data_set;
        par_mark;
        loss_value = nan;
        escape = 0; % 0 -default, 1 -busy, 2-abort
    end
    
    methods (Access = private)
        
        function setup_param_comps(app)
            par_names = fieldnames(app.model.par);
            %app.gl_params.Scrollable = 'on';
            app.gl_params.ColumnWidth = 290*ones(1,4);
            app.gl_params.RowHeight = 57*ones(1,ceil(numel(par_names)/numel(app.gl_params.ColumnWidth)));
            for i = 1:numel(par_names)
                value = app.model.par.(par_names{i});
                value_max = 2*value*(value~=0) + (value==0);
                app.param_comps{i} = apps.param_comp(app.gl_params,'ValueMin',0,'ValueMax',value_max,'Value',value,'Name',strrep(par_names{i},'_','\_'),'Index',i,'IsSelected',false,...
                    'ValueChangedFcn',@app.ValueUpdated,'ParamChangedFcn',@app.ParamUpdated,'ValueChangingFcn',@app.ValueUpdating,'OptPushedFcn',@app.OptPushed);
                %drawnow;
            end
            app.ind_bif_param = find(strcmp(par_names,'EGF_EGFRtt'));
            if isempty(app.ind_bif_param)
                app.ind_bif_param = 1;
            else
                app.param_comps{app.ind_bif_param}.SetHardBounds([0,1]);
            end
            app.param_comps{app.ind_bif_param}.Select();
            %drawnow;
            hold(app.ax_bif,'on');
            app.data_set = plot(app.ax_bif, nan, nan, 'o');
            app.line_profile_stable = plot(app.ax_bif, nan, nan, '-', 'linewidth', 2);
            app.line_profile_unstable = plot(app.ax_bif, nan, nan, '--', 'linewidth', 2);
            app.line_profile_unstable.Color = app.line_profile_stable.Color;
            app.par_mark = xline(app.ax_bif, app.model.par.(par_names{app.ind_bif_param}), '--', 'linewidth', 1);
            ylabel(app.ax_bif, 'Response');
            ylim(app.ax_bif, [0,1]);
            drawnow;
            app.update_bif_diagram();
        end

        function setup_data_fit(app)
            app.ind_data_set = 1;
            app.DatasetDropDown.Enable = true;
            app.DatasetDropDownLabel.Enable = true;
            app.DatasetDropDown.Items = app.data_fit.conditions;
            app.DatasetDropDown.Value = app.DatasetDropDown.Items{app.ind_data_set};
            app.update_data_set();
        end

        function update_param_comps(app)
            par_names = fieldnames(app.model.par);
            for i = 1:numel(par_names)
                value = app.model.par.(par_names{i});
                value_max = 2*value*(value~=0) + (value==0);
                app.param_comps{i}.ValueMax = value_max;
                app.param_comps{i}.Value = value;
                app.param_comps{i}.ValueMax = value_max;
                %drawnow;
            end
        end

        function update_data_set(app)
            if isempty(app.data_fit); return; end
            par_names = fieldnames(app.model.par);
            if app.ind_bif_param == find(strcmp(par_names,'EGF_EGFRtt'))
                set(app.data_set,'XData',app.data_fit.xx_master(:,app.ind_data_set),'YData',app.data_fit.yy_master(:,app.ind_data_set));
                for i=1:numel(app.param_comps)
                    if i ~= app.ind_bif_param
                        app.param_comps{i}.EnableOpt();
                    end
                end
                app.LossValue.Enable = true;
                app.LossvalueRMSELabel.Enable = true;
            else
                set(app.data_set,'XData',nan,'YData',nan);
                for i=1:numel(app.param_comps)
                    app.param_comps{i}.DisableOpt();
                end
                app.LossValue.Text = '';
                app.LossValue.Enable = false;
                app.LossvalueRMSELabel.Enable = false;
            end
            drawnow;
        end

        function OptPushed(app, source, event)
            set(app.BifurcationanalysisUIFigure,'Pointer','watch'); drawnow;
            par_names = fieldnames(app.model.par);
            par_value_curr = source.Value;
            par_value_prev = nan;
            rmse_prev = nan;
            rmse_min = inf;
            par_value_min = source.Value;
            step_size = .1;
            app.escape = 1;
            for i=1:500
                app.model.par.(par_names{source.Index}) = par_value_curr;
                [~, rmse_curr] = app.data_fit.dose_response_est_single(app.model, app.data_fit.xx_master(:,app.ind_data_set), app.data_fit.yy_master(:,app.ind_data_set));
                if rmse_min > rmse_curr
                    rmse_min = rmse_curr;
                    par_value_min = par_value_curr;
                    %source.Value = par_value_min;
                    %app.update_bif_diagram();
                end
                if isnan(par_value_prev)
                    par_value_prev = 1e-6 +1.01*par_value_curr;
                    app.model.par.(par_names{source.Index}) = par_value_prev;
                    [~, rmse_prev] = app.data_fit.dose_response_est_single(app.model, app.data_fit.xx_master(:,app.ind_data_set), app.data_fit.yy_master(:,app.ind_data_set));
                end
                grad_curr = (rmse_curr -rmse_prev)/(par_value_curr -par_value_prev);
                par_value_prev = par_value_curr;
                rmse_prev = rmse_curr;
                par_value_curr = par_value_curr -step_size*grad_curr;
                if par_value_curr < source.ValueMinHard
                    par_value_curr = source.ValueMinHard;
                elseif par_value_curr > source.ValueMaxHard
                    par_value_curr = source.ValueMaxHard;
                end
                drawnow;
                if (app.escape == 2) || isnan(par_value_curr) || (par_value_curr == par_value_prev) || ((i>1) && (abs(grad_curr) < 1e-4))
                    break;
                end
            end
            app.escape = 0;
            app.model.par.(par_names{source.Index}) = par_value_min;
            source.Value = par_value_min;
            app.update_bif_diagram();
            set(app.BifurcationanalysisUIFigure,'Pointer','arrow'); drawnow;
        end

        function ValueUpdated(app, source, event)
            app.update_model(source);
            [value_min, value_max] = app.param_comps{app.ind_bif_param}.GetSliderValues();
            if app.ind_bif_param ~= source.Index || ~isequal(app.ax_bif.XLim,[value_min,value_max])
                set(app.BifurcationanalysisUIFigure,'Pointer','watch'); drawnow;
                app.update_bif_diagram();
                set(app.BifurcationanalysisUIFigure,'Pointer','arrow'); drawnow;
            end
        end

        function ValueUpdating(app, source, event)
            app.par_mark.Value = event.NewState; %source.getTempValue();
        end

        function ParamUpdated(app, source, event)
            set(app.BifurcationanalysisUIFigure,'Pointer','watch'); drawnow;
            app.param_comps{app.ind_bif_param}.Deselect();
            app.ind_bif_param = source.Index;
            app.update_data_set();
            app.update_bif_diagram();
            set(app.BifurcationanalysisUIFigure,'Pointer','arrow'); drawnow;
        end

        function update_model(app, param)
            par_names = fieldnames(app.model.par);
            app.model.par.(par_names{param.Index}) = param.Value;
        end

        function update_bif_diagram(app)
            ct = dynamics.continuation(app.model);
            par_names = fieldnames(app.model.par);
            [value_min, value_max] = app.param_comps{app.ind_bif_param}.GetSliderValues();
            try
                ct.calc_profile(par_names{app.ind_bif_param}, [value_min, value_max], [0, 1]);
                branches_stable = ct.prof_bif.get_stable_branches(1);
                branches_unstable = ct.prof_bif.get_stable_branches(0);
                bif_par_values_stable = branches_stable(1,:);
                Epp_stable = ct.readout(branches_stable);
                set(app.line_profile_stable,'XData',bif_par_values_stable, 'YData',Epp_stable);
                bif_par_values_unstable = branches_unstable(1,:);
                Epp_unstable = ct.readout(branches_unstable);
                set(app.line_profile_unstable,'XData',bif_par_values_unstable, 'YData',Epp_unstable);
                title(app.ax_bif, ' ');
            catch
                set(app.line_profile_stable,'XData',nan, 'YData',nan);
                set(app.line_profile_unstable,'XData',nan, 'YData',nan);
                title(app.ax_bif, 'Error during the continuation execution!');
            end
            xlabel(app.ax_bif, strrep(par_names{app.ind_bif_param},'_','\_'));
            xlim(app.ax_bif, [value_min, value_max]); ylim(app.ax_bif, [0,1]);
            app.par_mark.Value = app.model.par.(par_names{app.ind_bif_param});
            if app.LossValue.Enable
                rmse_prev = app.loss_value;
                [y_ret, rmse] = app.data_fit.dose_response_est_single(app.model, app.data_fit.xx_master(:,app.ind_data_set), app.data_fit.yy_master(:,app.ind_data_set));
                app.loss_value = rmse;
                app.LossValue.Text = num2str(app.loss_value);
                if rmse_prev > rmse
                    app.LossValue.FontColor = [0,.5,0];
                    pause(.15);
                    app.LossValue.FontColor = 'k';
                elseif rmse_prev < rmse
                    app.LossValue.FontColor = 'r';
                    pause(.15);
                    app.LossValue.FontColor = 'k';
                end
            end
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            uts = get(0,'units'); set(0,'units','pixels');
            ss = get(0,'screensize');
            set(0,'units',uts);
            app.BifurcationanalysisUIFigure.Position(1:2) = round(ss(3:4)/2-app.BifurcationanalysisUIFigure.Position(3:4)/2);
            p = inputParser;
            addParameter(p,'data_fit',[]);
            addParameter(p,'model',models.egfr_ptprg_model);
            addParameter(p,'pars',[]);
            parse(p,varargin{:});
            app.model = p.Results.model;
            if ~isempty(p.Results.pars)
                app.model.set_params(p.Results.pars);
            end
            app.data_fit = p.Results.data_fit;
            app.setup_param_comps();
            if ~isempty(app.data_fit)
                app.setup_data_fit();
                app.update_bif_diagram();
            end
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            set(app.BifurcationanalysisUIFigure,'Pointer','watch'); drawnow;
            app.ind_data_set = find(strcmp(app.DatasetDropDown.Items,app.DatasetDropDown.Value));
            app.model.set_params(app.data_fit.par_master((app.ind_data_set-1)*app.data_fit.n_pars_model +(1:app.data_fit.n_pars_model)));
            app.update_param_comps();
            app.update_data_set();
            app.update_bif_diagram();
            set(app.BifurcationanalysisUIFigure,'Pointer','arrow'); drawnow;
        end

        % Callback function: BifurcationanalysisUIFigure, 
        % ...and 1 other component
        function BifurcationanalysisUIFigureKeyRelease(app, event)
            if strcmp(event.Key,'escape') && app.escape == 1
                app.escape = 2; % abort if busy
            end
        end

        % Callback function
        function AbortButtonPushed(app, event)
            app.escape = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BifurcationanalysisUIFigure = uifigure('Visible', 'off');
            app.BifurcationanalysisUIFigure.Name = 'Bifurcation analysis';
            app.BifurcationanalysisUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @BifurcationanalysisUIFigureKeyRelease, true);
            app.BifurcationanalysisUIFigure.KeyReleaseFcn = createCallbackFcn(app, @BifurcationanalysisUIFigureKeyRelease, true);
            app.GridLayout = uigridlayout(app.BifurcationanalysisUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.ax_bif = uiaxes(app.Panel);
            title(app.ax_bif, 'Title')
            xlabel(app.ax_bif, 'X')
            ylabel(app.ax_bif, 'Y')
            zlabel(app.ax_bif, 'Z')
            app.DatasetDropDownLabel = uilabel(app.Panel);
            app.DatasetDropDownLabel.Enable = 'off';
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.Panel);
            app.DatasetDropDown.Items = {};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Enable = 'off';
            app.DatasetDropDown.Value = {};
            app.LossvalueRMSELabel = uilabel(app.Panel);
            app.LossvalueRMSELabel.Enable = 'off';
            app.LossvalueRMSELabel.Text = 'Loss value (RMSE)';
            app.LossValue = uilabel(app.Panel);
            app.LossValue.Enable = 'off';
            app.LossValue.Text = '';
            app.gl_params = uigridlayout(app.GridLayout);
            % Show the figure after all components are created
            app.BifurcationanalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_bif(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BifurcationanalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BifurcationanalysisUIFigure)
        end
    end
end