classdef param_comp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Panel        matlab.ui.container.Panel
        LabelPanel   matlab.ui.container.Panel
        OptButton    matlab.ui.control.Button
        SliderMax    matlab.ui.control.NumericEditField
        SliderMin    matlab.ui.control.NumericEditField
        Spinner      matlab.ui.control.Spinner
        SliderParam  matlab.ui.control.Slider
        LabelParam   matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        OptPushed
        ParamChanged
        ValueChanged
        ValueChanging
    end

    properties (Access = public)
        Index double = 1;
        IsSelected logical = 0;
        Name string
        Value double = 0;
        ValueMax double = 1;
        ValueMaxHard double = Inf;
        ValueMin double = 0;
        ValueMinHard double = 0;
    end
    
    
    methods (Access = public)
        
        function Deselect(comp)
            comp.IsSelected = false;
            comp.Panel.BorderWidth = 1;
            drawnow;
        end

        function Select(comp)
            comp.IsSelected = true;
            comp.Panel.BorderWidth = 3;
            drawnow;
        end

        function EnableOpt(comp)
            comp.OptButton.Enable = true;
        end

        function DisableOpt(comp)
            comp.OptButton.Enable = false;
        end

        function [value_min, value_max] = GetSliderValues(comp)
            value_min = comp.SliderMin.Value;
            value_max = comp.SliderMax.Value;
        end

        function SetHardBounds(comp, bounds)
            comp.ValueMinHard = bounds(1);
            comp.ValueMaxHard = bounds(2);
            comp.Spinner.Limits = bounds;
        end

        function ret = getTempValue(comp)
            ret = comp.Spinner.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SliderParam, Spinner
        function SliderValueChanged(comp, event)
            comp.Value = event.Source.Value;
            notify(comp, 'ValueChanged');
            if comp.IsSelected
                notify(comp, 'ValueChanging', apps.SliderEventData(event.Value));
            end
        end

        % Value changed function: SliderMin
        function SliderMinValueChanged(comp, event)
            if comp.SliderMin.Value > comp.Value || comp.SliderMin.Value < comp.ValueMinHard
                comp.SliderMin.BackgroundColor = 'r';
                pause(.1);
                comp.SliderMin.BackgroundColor = 'w';
                comp.SliderMin.Value = comp.SliderParam.Limits(1);
            else
                comp.SliderParam.Limits(1) = comp.SliderMin.Value;
            end
            comp.ValueMin = comp.SliderMin.Value;
            notify(comp, 'ValueChanged');
        end

        % Value changed function: SliderMax
        function SliderMaxValueChanged(comp, event)
            if comp.SliderMax.Value < comp.Value || comp.SliderMax.Value > comp.ValueMaxHard
                comp.SliderMax.BackgroundColor = 'r';
                pause(.1);
                comp.SliderMax.BackgroundColor = 'w';
                comp.SliderMax.Value = comp.SliderParam.Limits(2);
            else
                comp.SliderParam.Limits(2) = comp.SliderMax.Value;
            end
            comp.ValueMax = comp.SliderMax.Value;
            notify(comp, 'ValueChanged');
        end

        % Value changing function: SliderParam
        function SliderParamValueChanging(comp, event)
            if comp.IsSelected
                notify(comp, 'ValueChanging', apps.SliderEventData(event.Value));
            end
            comp.Spinner.Value = event.Value;
        end

        % Button down function: LabelPanel, Panel
        function PanelButtonDown(comp, event)
            if ~comp.IsSelected
                comp.Select();
                notify(comp, 'ParamChanged');
            end
        end

        % Button pushed function: OptButton
        function OptButtonPushed(comp, event)
            notify(comp, 'OptPushed');
        end

        % Callback function
        function SpinnerValueChanged(comp, event)
            comp.Value = event.Source.Value;
            notify(comp, 'ValueChanged');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            if comp.SliderMin.Value ~= comp.ValueMin
                comp.SliderParam.Limits(1) = comp.ValueMin;
                comp.SliderMin.Value = comp.ValueMin;
                %notify(comp, 'ValueChanged');
                %drawnow;
            end

            if comp.SliderMax.Value ~= comp.ValueMax
                comp.SliderParam.Limits(2) = comp.ValueMax;
                comp.SliderMax.Value = comp.ValueMax;
                %notify(comp, 'ValueChanged');
                %drawnow;
            end

            if comp.Value ~= comp.Spinner.Value
                % if changed from Slider or programatically
                comp.Spinner.Value = comp.Value;
                %drawnow;
            end
            if comp.Value ~= comp.SliderParam.Value
                % if changed from Spinner or programatically
                if comp.Value > comp.SliderParam.Limits(2)
                    comp.SliderParam.Limits(2) = comp.Value;
                    comp.SliderMax.Value = comp.Value;
                    comp.ValueMax = min([comp.Value*1.2, comp.ValueMaxHard]);
                    return;
                elseif comp.Value < comp.SliderParam.Limits(1)
                    comp.SliderParam.Limits(1) = comp.Value;
                    comp.SliderMin.Value = comp.Value;
                    comp.ValueMin = max([comp.Value*0.8, comp.ValueMinHard]);
                    return;
                end
                %drawnow;
                comp.SliderParam.Value = comp.Value;
                %drawnow;
            end
            comp.Spinner.Step = (comp.ValueMax -comp.ValueMin)/100;

            if ~strcmp(comp.LabelPanel.Title,comp.Name)
                %comp.LabelParam.Text = comp.Name;
                comp.LabelPanel.Title = comp.Name;
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [0 0 285 55];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.Panel = uipanel(comp);
            comp.Panel.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @PanelButtonDown, true);
            comp.LabelParam = uilabel(comp.Panel);
            comp.LabelParam.Visible = 'off';
            comp.LabelParam.Text = 'Param.';
            comp.SliderParam = uislider(comp.Panel);
            comp.SliderParam.Limits = [0 1];
            comp.SliderParam.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SliderValueChanged, true);
            comp.SliderParam.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @SliderParamValueChanging, true);
            comp.Spinner = uispinner(comp.Panel);
            comp.Spinner.Step = 0.01;
            comp.Spinner.Limits = [0 Inf];
            comp.Spinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SliderValueChanged, true);
            comp.SliderMin = uieditfield(comp.Panel, 'numeric');
            comp.SliderMin.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SliderMinValueChanged, true);
            comp.SliderMax = uieditfield(comp.Panel, 'numeric');
            comp.SliderMax.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SliderMaxValueChanged, true);
            comp.OptButton = uibutton(comp.Panel, 'push');
            comp.OptButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @OptButtonPushed, true);
            comp.OptButton.Enable = 'off';
            comp.OptButton.Text = 'Opt.';
            comp.LabelPanel = uipanel(comp.Panel);
            comp.LabelPanel.Title = 'Label';
            comp.LabelPanel.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @PanelButtonDown, true);
        end
    end
end